classdef simulation_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LogPanel                        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ConsoleoutputTextArea           matlab.ui.control.TextArea
        GeneralPanel                    matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ResetsettingstodefaultButton    matlab.ui.control.Button
        SaveresultsButton               matlab.ui.control.Button
        RunsimulationButton             matlab.ui.control.Button
        ResetsimulationButton           matlab.ui.control.Button
        StopsimulationButton            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        DisturbanceAmplitude            matlab.ui.control.NumericEditField
        mLabel_3                        matlab.ui.control.Label
        InitialdisturbanceamplitudeLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        DisturbanceModeDropDown         matlab.ui.control.DropDown
        InitialdisturbancemodeLabel     matlab.ui.control.Label
        InitilSheetLengthEditField      matlab.ui.control.NumericEditField
        RegularizationValueEditField    matlab.ui.control.NumericEditField
        IntegratorTypeDropDown          matlab.ui.control.DropDown
        mLabel_2                        matlab.ui.control.Label
        InitialsheetlengthLabel         matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        RegularizationvalueLabel        matlab.ui.control.Label
        IntegratortypeLabel             matlab.ui.control.Label
        TimeofsimulationEditField       matlab.ui.control.NumericEditField
        TimeofsimulationEditFieldLabel  matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        LowerLayerVelocityEditField     matlab.ui.control.NumericEditField
        UpperLayerVelocitEditField      matlab.ui.control.NumericEditField
        LowerlayervelocityLabel         matlab.ui.control.Label
        UpperlayervelocityLabel         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        VortexSheetAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
          Params  % global parameters of simulation
          State   %  global state of vortex sheet
          CurrentTime = 0  % current time of simulation
          CurrentStep = 0  % current step of simulation
        
          x_hist = {} % container to store history of x-cordinates of vortex sheet for Mat file export
          y_hist = {} % container to store history of y-cordinates of vortex sheet for Mat file export
          t_hist = [] % container to store history of timesteps of simulation for Mat file export
          VideoFrames = {} % container to store Frames for video export
        
         IsRunning = false % global flag, whether simulation is running currently
    end
    
    methods (Access = private)
           % function called along with constructor to set configuration of
           % application
        function startupFcn(app)
            % Add folder with +kh package.
            addpath("src");
        
            % Create default parameters.
            app.Params = kh.makeDefaultParams();
        

            % Create initial vortex sheet.
            app.State = kh.initializeVortexSheet(app.Params);
        
            % Reset simulation state.
            app.CurrentTime = 0;
            app.CurrentStep = 0;
            app.IsRunning = false;
        
            % Reset histories.
            app.x_hist = {};
            app.y_hist = {};
            app.t_hist = [];
            app.VideoFrames = {};
        
            % Save initial state.
            app.x_hist{end+1} = app.State.x;
            app.y_hist{end+1} = app.State.y;
            app.t_hist(end+1) = app.CurrentTime;
        
            % Draw initial plot.
            kh.updateResultsPlot(app);
        
            % Log information.
            kh.appendLog(app, "Application started.", "INFO");
            kh.appendLog(app, "Default simulation initialized.", "INFO");

            % GUI update
            kh.paramsToApp(app, app.Params);
            end
    end
                

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
               startupFcn(app)
        end

        % Button pushed function: StopsimulationButton
        function StopsimulationButtonPushed(app, event)
            % Stop running simulation.
            app.IsRunning = false;
            kh.appendLog(app, "Stop requested.", "WARNING");
            drawnow;
        end

        % Button pushed function: ResetsimulationButton
        function ResetsimulationButtonPushed(app, event)
                
                app.IsRunning = false;
                app.Params = kh.appToParams(app);
                app.State = kh.initializeVortexSheet(app.Params);
        
                app.CurrentTime = 0;
                app.CurrentStep = 0;
        
                app.x_hist = {};
                app.y_hist = {};
                app.t_hist = [];
                app.VideoFrames = {};
        
                app.x_hist{end+1} = app.State.x;
                app.y_hist{end+1} = app.State.y;
                app.t_hist(end+1) = app.CurrentTime;
        
                kh.updateResultsPlot(app);
                kh.appendLog(app, "Simulation reset.", "INFO");
        end

        % Button pushed function: ResetsettingstodefaultButton
        function ResetsettingstodefaultButtonPushed(app, event)
               % reset parametrs of simulation to deafult
               app.Params = kh.makeDefaultParams();
               kh.paramsToApp(app, app.Params);
               % GUI update
               kh.appendLog(app, "Paramaters reset.", "INFO");
        end

        % Callback function: not associated with a component
        function StopsimulationButtonPushed2(app, event)
                
        end

        % Button pushed function: RunsimulationButton
        function RunsimulationButtonPushed(app, event)

                % In case of double-click on Run simulation button
                if app.IsRunning
                     kh.appendLog(app, "Simulation is already running.", "WARNING");
                     return;
                end
                
                % Preparation to simulation
                app.Params = kh.appToParams(app);
                app.State = kh.initializeVortexSheet(app.Params);
                app.IsRunning = true;
                kh.logSimulationStart(app);

                while app.IsRunning && app.CurrentStep < app.Params.nSteps

                    stepTimer = tic;
                    
                    % perform one step of physics evolution
                    app.State = kh.simulationStep(app.State, app.Params);
                    
                    % update global time, time length of integration and number of step
                    stepComputeTime = toc(stepTimer);
        
                    app.CurrentStep = app.CurrentStep + 1;
                    app.CurrentTime = app.CurrentTime + app.Params.dt;
        
                    % save history.
                    app.x_hist{end+1} = app.State.x;
                    app.y_hist{end+1} = app.State.y;
                    app.t_hist(end+1) = app.CurrentTime;
        
                    % update plot not every step, only every plotEvery steps.
                    if mod(app.CurrentStep, app.Params.plotEvery) == 0
                        kh.updateResultsPlot(app);
                        app.VideoFrames{end+1} = getframe(app.VortexSheetAxes);
                    end
                    % send debug info to console every 10 steps.
                    if mod(app.CurrentStep, 10) == 0
                    kh.appendLog(app, sprintf( ...
                        "step = %d / %d, N = %d, step compute time = %.5f s", ...
                        app.CurrentStep, ...
                        app.Params.nSteps, ...
                        numel(app.State.x), ...
                        stepComputeTime), ...
                        "INFO");
                    end
                    % Let matlab process other callbacks
                    drawnow;
                end
                
           if app.CurrentStep >= app.Params.nSteps
                kh.appendLog(app, "Simulation finished.", "INFO");
           else
                kh.appendLog(app, "Simulation stopped.", "INFO");
           end
           % Set global running flag to false
           app.IsRunning = false;

        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
                 % Save simulation vectors and video to selected folder.
        
                % Choose output folder.
                outputFolder = uigetdir(pwd, "Select folder for saving results");
        
                % If user clicked Cancel.
                if isequal(outputFolder, 0)
                    kh.appendLog(app, "Saving cancelled.", "WARNING");
                    return;
                end
        
                % Fixed output filenames.
                matFile = fullfile(outputFolder, "vortex_sheet_evolution_vectors.mat");
                videoFile = fullfile(outputFolder, "vortex_sheet_evolution_video.mp4");
                x_hist = app.x_hist;
                y_hist = app.y_hist;
                t_hist = app.t_hist;
                params = app.Params;
        
                save(matFile, "x_hist", "y_hist", "t_hist", "params");
        
                kh.appendLog(app, "Saved MAT file: " + string(matFile),"INFO");
        
                % If no frames were stored yet, save current figure as one frame.
                if isempty(app.VideoFrames)
                    kh.updateResultsPlot(app);
                    app.VideoFrames{end+1} = getframe(app.VortexSheetAxes);
                end
        
                videoWriter = VideoWriter(videoFile, "MPEG-4");
                videoWriter.FrameRate = 20;
        
                open(videoWriter);
        
                for k = 1:numel(app.VideoFrames)
                    writeVideo(videoWriter, app.VideoFrames{k});
                end
        
                close(videoWriter);
        
                kh.appendLog(app, "Saved video file: " + string(videoFile), "INFO");
        
                kh.appendLog(app, "Results saved successfully.", "INFO");

           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout3 = uigridlayout(app.SettingsTab);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.msLabel_2 = uilabel(app.GridLayout3);
            app.msLabel_2.Text = 'm/s';
            app.sLabel = uilabel(app.GridLayout3);
            app.sLabel.Text = 's';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '-';
            app.UpperlayervelocityLabel = uilabel(app.GridLayout3);
            app.UpperlayervelocityLabel.Text = 'Upper layer velocity';
            app.LowerlayervelocityLabel = uilabel(app.GridLayout3);
            app.LowerlayervelocityLabel.Text = 'Lower layer velocity';
            app.UpperLayerVelocitEditField = uieditfield(app.GridLayout3, 'numeric');
            app.LowerLayerVelocityEditField = uieditfield(app.GridLayout3, 'numeric');
            app.msLabel = uilabel(app.GridLayout3);
            app.msLabel.Text = 'm/s';
            app.TimeofsimulationEditFieldLabel = uilabel(app.GridLayout3);
            app.TimeofsimulationEditFieldLabel.Text = 'Time of simulation';
            app.TimeofsimulationEditField = uieditfield(app.GridLayout3, 'numeric');
            app.IntegratortypeLabel = uilabel(app.GridLayout3);
            app.IntegratortypeLabel.Text = 'Integrator type';
            app.RegularizationvalueLabel = uilabel(app.GridLayout3);
            app.RegularizationvalueLabel.Text = 'Regularization value';
            app.mLabel = uilabel(app.GridLayout3);
            app.mLabel.Text = 'm';
            app.InitialsheetlengthLabel = uilabel(app.GridLayout3);
            app.InitialsheetlengthLabel.Text = 'Initial sheet length';
            app.mLabel_2 = uilabel(app.GridLayout3);
            app.mLabel_2.Text = 'm';
            app.IntegratorTypeDropDown = uidropdown(app.GridLayout3);
            app.IntegratorTypeDropDown.Items = {'RK4', 'Euler'};
            app.IntegratorTypeDropDown.Value = 'RK4';
            app.RegularizationValueEditField = uieditfield(app.GridLayout3, 'numeric');
            app.InitilSheetLengthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.InitialdisturbancemodeLabel = uilabel(app.GridLayout3);
            app.InitialdisturbancemodeLabel.Text = 'Initial disturbance mode';
            app.DisturbanceModeDropDown = uidropdown(app.GridLayout3);
            app.DisturbanceModeDropDown.Items = {'sin', 'noise'};
            app.DisturbanceModeDropDown.Value = 'sin';
            app.Label_4 = uilabel(app.GridLayout3);
            app.Label_4.Text = '-';
            app.InitialdisturbanceamplitudeLabel = uilabel(app.GridLayout3);
            app.InitialdisturbanceamplitudeLabel.Text = 'Initial disturbance amplitude';
            app.mLabel_3 = uilabel(app.GridLayout3);
            app.mLabel_3.Text = 'm';
            app.DisturbanceAmplitude = uieditfield(app.GridLayout3, 'numeric');
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.GridLayout5 = uigridlayout(app.ResultsTab);
            app.VortexSheetAxes = uiaxes(app.GridLayout5);
            title(app.VortexSheetAxes, 'Title')
            xlabel(app.VortexSheetAxes, 'X')
            ylabel(app.VortexSheetAxes, 'Y')
            zlabel(app.VortexSheetAxes, 'Z')
            app.GeneralPanel = uipanel(app.GridLayout);
            app.GeneralPanel.Title = 'General';
            app.GridLayout2 = uigridlayout(app.GeneralPanel);
            app.StopsimulationButton = uibutton(app.GridLayout2, 'push');
            app.StopsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StopsimulationButtonPushed, true);
            app.StopsimulationButton.Text = 'Stop simulation';
            app.ResetsimulationButton = uibutton(app.GridLayout2, 'push');
            app.ResetsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetsimulationButtonPushed, true);
            app.ResetsimulationButton.Text = 'Reset simulation';
            app.RunsimulationButton = uibutton(app.GridLayout2, 'push');
            app.RunsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunsimulationButtonPushed, true);
            app.RunsimulationButton.Text = 'Run simulation';
            app.SaveresultsButton = uibutton(app.GridLayout2, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Text = 'Save results';
            app.ResetsettingstodefaultButton = uibutton(app.GridLayout2, 'push');
            app.ResetsettingstodefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResetsettingstodefaultButtonPushed, true);
            app.ResetsettingstodefaultButton.Text = 'Reset settings to default';
            app.LogPanel = uipanel(app.GridLayout);
            app.LogPanel.Title = 'Log/Debug console';
            app.GridLayout4 = uigridlayout(app.LogPanel);
            app.ConsoleoutputTextArea = uitextarea(app.GridLayout4);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end